classdef LayeredMedia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CantidaddecapasSliderLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        FiltrospticosdemediosmulticapaLayeredMediaLabel  matlab.ui.control.Label
        RenderizarButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ButtonGroup                 matlab.ui.container.ButtonGroup
        BetaButton                  matlab.ui.control.RadioButton
        LambdaButton                matlab.ui.control.RadioButton
        BetaEditField               matlab.ui.control.NumericEditField
        LambdaEditField             matlab.ui.control.NumericEditField
        LongitudDesplegable         matlab.ui.control.DropDown
        radmLabel                   matlab.ui.control.Label
        LambdaDesplegable           matlab.ui.control.DropDown
        LongitudEditField_2Label    matlab.ui.control.Label
        LongitudEditField           matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Medio1EditField_2Label      matlab.ui.control.Label
        Medio1EditField             matlab.ui.control.NumericEditField
        Medio2EditField_2Label      matlab.ui.control.Label
        Medio2EditField             matlab.ui.control.NumericEditField
        reaparapruebasymensajesTextAreaLabel  matlab.ui.control.Label
        Prueba                      matlab.ui.control.TextArea
        AyudaButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RenderizarButton
        function RenderizarButtonPushed(app, event)
            medio1 = app.Medio1EditField.Value;
            medio2 = app.Medio2EditField.Value;
            etaEQ=0;
            
            
            valor = app.Slider.Value;
            
            
            bl = app.BetaEditField.Value; % Toma el Beta de una vez, si elige el Lambda, se procede a sustituirlo
            if(app.ButtonGroup.SelectedObject == app.LambdaButton)
                lambda = app.LambdaEditField.Value;
                opLambda = app.LambdaDesplegable.Value;
                switch opLambda % Se hace la conversión con las unidades
                    case 'dm'
                        bl = 2*pi/(lambda*0.1);   
                    case 'cm'
                        bl = 2*pi/(lambda*0.01);   
                    case 'mm'
                        bl = 2*pi/(lambda*0.001);
                    case 'μm'
                        bl = 2*pi/(lambda*0.000001);   
                    case 'nm'
                        bl = 2*pi/(lambda*0.000000001);  
                    case 'pm'
                        bl = 2*pi/(lambda*0.000000000001);
                    otherwise
                        bl = 2*pi/lambda;
                end
            end
            

            longitud = app.LongitudEditField.Value; % Se obtiene el valor de l
            l = longitud;
            opLongitud = app.LongitudDesplegable.Value;            
            switch opLongitud % Se hace la conversión con las unidades
                case 'dm'
                    longitud = (l*0.1); 
                case 'cm'
                    longitud = (l*0.01);  
                case 'mm'
                    longitud = (l*0.001);
                case 'μm'
                    longitud = (l*0.000001);   
                case 'nm'
                    longitud = (l*0.000000001);   
                case 'pm'
                    longitud = (l*0.000000000001);
            end
            %%%%%%%%%%%%%%%%%%%%% Fin Declaración de variables %%%%%%%%%%%%%%%%%%%%%%%%%


            % Aquí van todos los mensajes en pantalla de: "Error capa 8" / "Error 404"
            % PE: medio1,medio2,longitud,bl > 0 


            %%%%%%%%%%%%%%%%%%%%%%%% Fin Manejo de excepciones %%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            p=mod(valor,2); % Variable que controla si está en una capa par o impar
            
            if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
               etaEQ = medio2*((medio1+1j*medio2*tan(bl*longitud))/(medio2+1j*medio1*tan(bl*longitud))); 
               
            else % Es par (La primera es n1, y la última n2)
               etaEQ = medio1*((medio2+1j*medio1*tan(bl*longitud))/(medio1+1j*medio2*tan(bl*longitud)));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            for i = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
                       % Le resto 3 al valor ya que en el setup inicial calculé para 2 medios
               if(p==1)% Si la cantidad de capas es < 3, no entra aquí, y no pasa nada. 
                    etaEQ = medio2*((etaEQ+1j*medio2*tan(bl*longitud))/(medio2+1j*etaEQ*tan(bl*longitud)));
                    p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
               else
                    etaEQ = medio1*((etaEQ+1j*medio1*tan(bl*longitud))/(medio1+1j*etaEQ*tan(bl*longitud)));
                    p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
               end 
            end
            
            app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ;
            %%%%%%%%%%%%%%%%%%%%%%%%% Fin del núcleo lógico %%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CantidaddecapasSliderLabel = uilabel(app.UIFigure);
            app.CantidaddecapasSliderLabel.Text = {'Cantidad '; 'de capas'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [2 25];
            app.Slider.MajorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MinorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.Value = 2;
            app.FiltrospticosdemediosmulticapaLayeredMediaLabel = uilabel(app.UIFigure);
            app.FiltrospticosdemediosmulticapaLayeredMediaLabel.Text = 'Filtros ópticos de medios multicapa [ Layered Media ]';
            app.RenderizarButton = uibutton(app.UIFigure, 'push');
            app.RenderizarButton.ButtonPushedFcn = createCallbackFcn(app, @RenderizarButtonPushed, true);
            app.RenderizarButton.Text = 'Renderizar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Espectro de Potencia')
            xlabel(app.UIAxes, '\lambda')
            ylabel(app.UIAxes, 'P')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.BetaButton = uiradiobutton(app.ButtonGroup);
            app.BetaButton.Text = 'β ';
            app.BetaButton.Value = true;
            app.LambdaButton = uiradiobutton(app.ButtonGroup);
            app.LambdaButton.Text = 'λ ';
            app.BetaEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.BetaEditField.Value = 1;
            app.LambdaEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.LambdaEditField.Value = 8;
            app.LongitudDesplegable = uidropdown(app.ButtonGroup);
            app.LongitudDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LongitudDesplegable.Value = 'μm';
            app.radmLabel = uilabel(app.ButtonGroup);
            app.radmLabel.Text = 'rad/m';
            app.LambdaDesplegable = uidropdown(app.ButtonGroup);
            app.LambdaDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LambdaDesplegable.Value = 'mm';
            app.LongitudEditField_2Label = uilabel(app.ButtonGroup);
            app.LongitudEditField_2Label.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.LongitudEditField.Value = 40;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ω';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Ω';
            app.Medio1EditField_2Label = uilabel(app.UIFigure);
            app.Medio1EditField_2Label.Text = 'Medio η1';
            app.Medio1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Medio1EditField.Value = 343;
            app.Medio2EditField_2Label = uilabel(app.UIFigure);
            app.Medio2EditField_2Label.Text = 'Medio η2';
            app.Medio2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Medio2EditField.Value = 2048;
            app.reaparapruebasymensajesTextAreaLabel = uilabel(app.UIFigure);
            app.reaparapruebasymensajesTextAreaLabel.Text = 'Área para pruebas y mensajes';
            app.Prueba = uitextarea(app.UIFigure);
            app.Prueba.Value = {'La ayuda aún no hace nada.'; ''; 'Completamente irónico :v'};
            app.AyudaButton = uibutton(app.UIFigure, 'push');
            app.AyudaButton.Text = '¿Ayuda?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LayeredMedia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end